classdef EEGsonic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGSonicUIFigure                matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveSettingsMenu                matlab.ui.container.Menu
        QuitEEGsonicMenu                matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        GeneralPanel                    matlab.ui.container.Panel
        SelectSavingDirectoryButton     matlab.ui.control.Button
        SavingDirectoryLabel            matlab.ui.control.Label
        SonificationTab                 matlab.ui.container.Tab
        SonificationPanel               matlab.ui.container.Panel
        SpectralPowerRatioTab           matlab.ui.container.Tab
        SpectralPowerRatioPanel         matlab.ui.container.Panel
        TopographicDistributionTab      matlab.ui.container.Tab
        TopographicDistributionPanel    matlab.ui.container.Panel
        PhaseAmplitudeCouplingTab       matlab.ui.container.Tab
        PhaseAmplitudeCouplingPanel     matlab.ui.container.Panel
        FrontoparietalwPLITab           matlab.ui.container.Tab
        FrontoparietalWeightedPhaseLagIndexPanel  matlab.ui.container.Panel
        FrontoparietaldPLITab           matlab.ui.container.Tab
        FrontoparietalDirectedPhaseLagIndexPanel  matlab.ui.container.Panel
        HubLocationTab                  matlab.ui.container.Tab
        HubLocationPanel                matlab.ui.container.Panel
        PermutationEntropyTab           matlab.ui.container.Tab
        PermutationEntropyPanel         matlab.ui.container.Panel
        EEGSonicEGIeditionPanel         matlab.ui.container.Panel
        LaunchEEGsonicButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        SpectralPowerRatioCheckBox      matlab.ui.control.CheckBox
        TopographicDistributionCheckBox  matlab.ui.control.CheckBox
        PhaseAmplitudeCouplingCheckBox  matlab.ui.control.CheckBox
        FrontoparietalwPLICheckBox      matlab.ui.control.CheckBox
        FrontoparietaldPLICheckBox      matlab.ui.control.CheckBox
        HubLocationCheckBox             matlab.ui.control.CheckBox
        PermutationEntropyCheckBox      matlab.ui.control.CheckBox
        SonificationCheckBox            matlab.ui.control.CheckBox
    end


    properties (Access = private)
        % Strings
        start_message;
        exit_message;
        
        parameters;
        information;
    end

    methods (Access = private)
    
        function save_gui(app)
            % Save checkbox selection
            app.parameters.sonification.is_selected = app.SonificationCheckBox.Value;
            app.parameters.spr.is_selected = app.SpectralPowerRatioCheckBox.Value;
            app.parameters.td.is_selected = app.TopographicDistributionCheckBox.Value;
            app.parameters.pac.is_selected = app.PhaseAmplitudeCouplingCheckBox.Value;
            app.parameters.fp_wpli.is_selected = app.FrontoparietalwPLICheckBox.Value;
            app.parameters.fp_dPLI.is_selected = app.FrontoparietaldPLICheckBox.Value;
            app.parameters.hl.is_selected = app.HubLocationCheckBox.Value;
            app.parameters.pe.is_selected = app.PermutationEntropyCheckBox.Value;
        end


        function update_gui(app)
            % Update Checkboxes
            app.SonificationCheckBox.Value = app.parameters.sonification.is_selected;
            app.SpectralPowerRatioCheckBox.Value = app.parameters.spr.is_selected;
            app.TopographicDistributionCheckBox.Value = app.parameters.td.is_selected;
            app.PhaseAmplitudeCouplingCheckBox.Value = app.parameters.pac.is_selected;
            app.FrontoparietalwPLICheckBox.Value = app.parameters.fp_wpli.is_selected;
            app.FrontoparietaldPLICheckBox.Value = app.parameters.fp_dPLI.is_selected;
            app.HubLocationCheckBox.Value = app.parameters.hl.is_selected;
            app.PermutationEntropyCheckBox.Value = app.parameters.pe.is_selected;     
            
            % Update text fields
            app.SavingDirectoryLabel.Text = app.information.saving_directory;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Settinng up String
            app.start_message = "Welcome to EEGsonic, an application by the Biosignal Interaction and Personhood Technology (BIAPT) lab at McGill";
            app.exit_message = "Thank you for using EEGsonic! If there is an issue with the code please send a message to yacine.mahdid@mail.mcgill.ca or visit https://github.com/BIAPT/EEGsonic/issues to open an issue";
            
            % Setup
            base_directory = erase(mfilename('fullpath'),strcat('\',mfilename));
            
            disp(mfilename('fullpath'));
            % Setting up Variables
            app_data = load(strcat(base_directory,'/data/app_data'));
            app.parameters = app_data.parameters;
            app.information = app_data.information;
            app.information.base_directory = base_directory;
            
            % Welcoming the user
            clc;
            disp(app.start_message);
            
            % Populating the GUI with saved data
            update_gui(app);
            
        end

        % Menu selected function: QuitEEGsonicMenu
        function QuitEEGsonicMenuSelected(app, event)
            disp(app.exit_message);
            app.delete();
        end

        % Menu selected function: SaveSettingsMenu
        function SaveSettingsMenuSelected(app, event)
            disp('Saving Settings...');
            save_gui(app);
            parameters = app.parameters;
            information = app.information;
            save(strcat(app.information.base_directory,'/data/app_data'),'parameters','-append');
            save(strcat(app.information.base_directory,'/data/app_data'),'information','-append');
            disp('Finished Saving!');
        end

        % Button pushed function: SelectSavingDirectoryButton
        function SelectSavingDirectoryButtonPushed(app, event)
            path = uigetdir;
            if(path ~= 0)
               app.information.saving_directory = path;
               app.SavingDirectoryLabel.Text = path;
               disp(['Updated saving directory to : ',path]);
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGSonicUIFigure = uifigure;
            app.EEGSonicUIFigure.Name = 'EEG Sonic';
            app.FileMenu = uimenu(app.EEGSonicUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveSettingsMenu = uimenu(app.FileMenu);
            app.SaveSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSettingsMenuSelected, true);
            app.SaveSettingsMenu.Text = 'Save Settings';
            app.QuitEEGsonicMenu = uimenu(app.FileMenu);
            app.QuitEEGsonicMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitEEGsonicMenuSelected, true);
            app.QuitEEGsonicMenu.Text = 'Quit EEGsonic';
            app.TabGroup = uitabgroup(app.EEGSonicUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.GeneralPanel = uipanel(app.GeneralTab);
            app.GeneralPanel.Title = 'General';
            app.SelectSavingDirectoryButton = uibutton(app.GeneralPanel, 'push');
            app.SelectSavingDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSavingDirectoryButtonPushed, true);
            app.SelectSavingDirectoryButton.Text = 'Select Saving Directory';
            app.SavingDirectoryLabel = uilabel(app.GeneralPanel);
            app.SavingDirectoryLabel.Text = 'No saving directory selected';
            app.SonificationTab = uitab(app.TabGroup);
            app.SonificationTab.Title = 'Sonification';
            app.SonificationPanel = uipanel(app.SonificationTab);
            app.SonificationPanel.Title = 'Sonification';
            app.SpectralPowerRatioTab = uitab(app.TabGroup);
            app.SpectralPowerRatioTab.Title = 'Spectral Power Ratio';
            app.SpectralPowerRatioPanel = uipanel(app.SpectralPowerRatioTab);
            app.SpectralPowerRatioPanel.Title = 'Spectral Power Ratio';
            app.TopographicDistributionTab = uitab(app.TabGroup);
            app.TopographicDistributionTab.Title = 'Topographic Distribution';
            app.TopographicDistributionPanel = uipanel(app.TopographicDistributionTab);
            app.TopographicDistributionPanel.Title = 'Topographic Distribution';
            app.PhaseAmplitudeCouplingTab = uitab(app.TabGroup);
            app.PhaseAmplitudeCouplingTab.Title = 'Phase Amplitude Coupling';
            app.PhaseAmplitudeCouplingPanel = uipanel(app.PhaseAmplitudeCouplingTab);
            app.PhaseAmplitudeCouplingPanel.Title = 'Phase Amplitude Coupling';
            app.FrontoparietalwPLITab = uitab(app.TabGroup);
            app.FrontoparietalwPLITab.Title = 'Frontoparietal wPLI';
            app.FrontoparietalWeightedPhaseLagIndexPanel = uipanel(app.FrontoparietalwPLITab);
            app.FrontoparietalWeightedPhaseLagIndexPanel.Title = 'Frontoparietal Weighted Phase Lag Index';
            app.FrontoparietaldPLITab = uitab(app.TabGroup);
            app.FrontoparietaldPLITab.Title = 'Frontoparietal dPLI';
            app.FrontoparietalDirectedPhaseLagIndexPanel = uipanel(app.FrontoparietaldPLITab);
            app.FrontoparietalDirectedPhaseLagIndexPanel.Title = 'Frontoparietal Directed Phase Lag Index';
            app.HubLocationTab = uitab(app.TabGroup);
            app.HubLocationTab.Title = 'Hub Location';
            app.HubLocationPanel = uipanel(app.HubLocationTab);
            app.HubLocationPanel.Title = 'Hub Location';
            app.PermutationEntropyTab = uitab(app.TabGroup);
            app.PermutationEntropyTab.Title = 'Permutation Entropy';
            app.PermutationEntropyPanel = uipanel(app.PermutationEntropyTab);
            app.PermutationEntropyPanel.Title = 'Permutation Entropy';
            app.EEGSonicEGIeditionPanel = uipanel(app.EEGSonicUIFigure);
            app.EEGSonicEGIeditionPanel.Title = 'EEGSonic (EGI edition)';
            app.LaunchEEGsonicButton = uibutton(app.EEGSonicEGIeditionPanel, 'push');
            app.LaunchEEGsonicButton.Text = 'Launch EEGsonic';
            app.Label = uilabel(app.EEGSonicEGIeditionPanel);
            app.Label.Text = 'EEGsonic is an application by the Biosignal Interaction and Personhood Technology (BIAPT) lab at McGill';
            app.Label_2 = uilabel(app.EEGSonicEGIeditionPanel);
            app.Label_2.Text = 'For comment or feedback please direct them to yacine.mahdid@mail.mcgill.ca';
            app.Label_3 = uilabel(app.EEGSonicEGIeditionPanel);
            app.Label_3.Text = 'or open an issue on the official github repository : https://github.com/BIAPT/EEGsonic ';
            app.SpectralPowerRatioCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.SpectralPowerRatioCheckBox.Text = '';
            app.TopographicDistributionCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.TopographicDistributionCheckBox.Text = '';
            app.PhaseAmplitudeCouplingCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.PhaseAmplitudeCouplingCheckBox.Text = '';
            app.FrontoparietalwPLICheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.FrontoparietalwPLICheckBox.Text = '';
            app.FrontoparietaldPLICheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.FrontoparietaldPLICheckBox.Text = '';
            app.HubLocationCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.HubLocationCheckBox.Text = '';
            app.PermutationEntropyCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.PermutationEntropyCheckBox.Text = '';
            app.SonificationCheckBox = uicheckbox(app.EEGSonicUIFigure);
            app.SonificationCheckBox.Text = '';
            app.SonificationCheckBox.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEGsonic_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGSonicUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGSonicUIFigure)
        end
    end
end